classdef DEERefiner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DEERefinerUIFigure              matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        INPUTgeneralTab                 matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        AdvancedoptionsPanel            matlab.ui.container.Panel
        PDBevolutionCheckBox            matlab.ui.control.CheckBox
        UsageofcoresEditField           matlab.ui.control.NumericEditField
        UsageofcoresEditFieldLabel      matlab.ui.control.Label
        NumberofstructureEditField      matlab.ui.control.NumericEditField
        NumberofstructureEditFieldLabel  matlab.ui.control.Label
        MaximalclashesEditField         matlab.ui.control.NumericEditField
        MaximalclashesEditFieldLabel    matlab.ui.control.Label
        RMSEEditField                   matlab.ui.control.NumericEditField
        RMSEEditFieldLabel              matlab.ui.control.Label
        MCtempEditField                 matlab.ui.control.NumericEditField
        MCtempEditFieldLabel            matlab.ui.control.Label
        MCiterationEditField            matlab.ui.control.NumericEditField
        MCiterationEditFieldLabel       matlab.ui.control.Label
        phipsiangleEditField            matlab.ui.control.NumericEditField
        phipsiangleEditFieldLabel       matlab.ui.control.Label
        ApplyButton                     matlab.ui.control.Button
        FlexibleregionsEditField        matlab.ui.control.EditField
        FlexibleregionsEditFieldLabel   matlab.ui.control.Label
        ValidationPanel                 matlab.ui.container.Panel
        Stage5EditField                 matlab.ui.control.NumericEditField
        Stage5EditFieldLabel            matlab.ui.control.Label
        Stage4EditField                 matlab.ui.control.NumericEditField
        Stage4EditFieldLabel            matlab.ui.control.Label
        Stage3EditField                 matlab.ui.control.NumericEditField
        Stage3EditFieldLabel            matlab.ui.control.Label
        Stage2EditField                 matlab.ui.control.NumericEditField
        Stage2EditFieldLabel            matlab.ui.control.Label
        Stage1EditField                 matlab.ui.control.NumericEditField
        Stage1EditFieldLabel            matlab.ui.control.Label
        WaitingForValidatingParameters  matlab.ui.control.CheckBox
        StopButton                      matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        WaitingforcheckingPDBfileCheckBox  matlab.ui.control.CheckBox
        WaitingformfilesdelectedCheckBox  matlab.ui.control.CheckBox
        WaitingForCPUCoresDetectedCheckBox  matlab.ui.control.CheckBox
        StartButton                     matlab.ui.control.Button
        ValidateButton                  matlab.ui.control.Button
        INPUTinitialstructurePanel      matlab.ui.container.Panel
        SelectPrfilesEditField          matlab.ui.control.EditField
        SelectPrfilesEditFieldLabel     matlab.ui.control.Label
        BrowseButton_P_r                matlab.ui.control.Button
        SelectinputPDBfileEditFieldLabel  matlab.ui.control.Label
        InitialStructureEditField       matlab.ui.control.EditField
        BrowseButton_initial_struc      matlab.ui.control.Button
        INPUTPrTab                      matlab.ui.container.Tab
        ResettargetdistanceButton       matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        ClickToRemoveSelectedPrButton   matlab.ui.control.Button
        SelectingRemovingSpinPairSpinner  matlab.ui.control.Spinner
        SelectremovingspinpairSpinnerLabel  matlab.ui.control.Label
        TargetDistanceEditField         matlab.ui.control.NumericEditField
        TargetdistancenmEditFieldLabel  matlab.ui.control.Label
        Residue2EditField               matlab.ui.control.NumericEditField
        Residue2EditFieldLabel          matlab.ui.control.Label
        Residue1EditField               matlab.ui.control.NumericEditField
        Residue1EditFieldLabel          matlab.ui.control.Label
        DEERRefineRestraintsTable       matlab.ui.control.Table
        ClicktoinputButton              matlab.ui.control.Button
        AxesForPrTargeting              matlab.ui.control.UIAxes
    end


    properties (Access = public)
        numberOfCPUCores 
        initialStructureFullPath
        initialStructurePath
        initialStructureFileName
        numberOfTimesPrLoadingButtonHit
        distanceDistributionFullPath_temp 
        distanceDistributionFullPath string
        distanceDistributionFileNameTmp
        distanceDistributionFileName string
        distanceDistributionTarget
        rawDistanceDistribution
        distanceDistributionTarger
        spinPairResidue1
        spinPairResidue2
        rowOfTableToBeColored
        runFileName string
        DEERRefineJob 
        initialMutatedFormatedPDB
        loopRegionString
        RMSE
        residueIndex
        flexiblePhiPsiIndex
        phiPsiAngle
        monteCarloIteration
        monteCarloTemperature
        maximalClashes
        numberOfStructure
        structureIndexEachScript
        currentStatusDetectorTimer = timer('Period',0.5,                   ...
                                           'StartDelay',0,                 ...
                                           'TasksToExecute',inf,           ...
                                           'ExecutionMode','fixedSpacing');
        fullyStartFlag
        validatingFlag
        distanceDistributionPlottingAxes
        PDBEvolution
    end
 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% CPU cores detection
            
            app.numberOfCPUCores = CPUcoreReader; 
            StringIfCPUCoresDetected = checkIfCPUCoresDetected(app.numberOfCPUCores);
            if StringIfCPUCoresDetected == "Detected"
                app.UsageofcoresEditField.Value = (app.numberOfCPUCores);
                checkingWaitingForCPUCoresDetectedCheckBox(app)
            else
                errorCPUCoresNonDetected
            end
            
            %% P_r files numbers
            
            app.numberOfTimesPrLoadingButtonHit = 0; % reset the P_r numbers to 0
            
            %% determine initial parameter
            
            parameterInitializer(app)
            
            warning('off', 'parallel:convenience:BatchScriptWithExtension');
            warning('off', 'MATLAB:appdesigner:appdesigner:SaveObjWarning');
            warning('off', 'MATLAB:appdesigner:appdesigner:LoadObjWarning');
        end

        % Button pushed function: BrowseButton_initial_struc
        function BrowseButton_initial_strucPushed(app, event)
            %% load the initial pdb file
            
            [app.initialStructureFullPath, app.initialStructurePath, app.initialStructureFileName] = loadingInitialStructure;
            
            if app.initialStructureFileName==0
              return
            end
            
            %% make the box green to notify
            
            checkingInitialStructureEditField(app);
            
            %% mutate initial pdb file to DEERefine readable pdb file
            
            app.initialMutatedFormatedPDB = DEERefineInitialSturctureMutator(app);
            
            %% determine default loop regions
            
            app.loopRegionString = loopRegionDeterminer(app);
            
            %% input loop region automatically
            
            loopRegionAutoInputer(app)
            
            %% input clashes number automatically
            
            clashesAutoInputer(app)
            
            %% detect residue numbers
            
            app.residueIndex = inititalStructureResidueIndexDetector(app);
            
            %% check PDB file
            
            PDBFileChecker(app)
            
        end

        % Button pushed function: BrowseButton_P_r
        function BrowseButton_P_rPushed(app, event)
            %% Increasing counting of PrLoadingButtonHits
            
            increasingPrLoadingButtonHit(app)
            
            
            %% Selecting the Pr file
            
            [app.distanceDistributionFullPath_temp, app.distanceDistributionFileNameTmp] = loadingDistanceDistribution;
            if app.distanceDistributionFileNameTmp==""
              return
            end
            app.distanceDistributionFileName(app.numberOfTimesPrLoadingButtonHit) = string(app.distanceDistributionFileNameTmp);
            app.distanceDistributionFullPath(app.numberOfTimesPrLoadingButtonHit) = string(app.distanceDistributionFullPath_temp);
            clear app.distanceDistributionFullPath_temp
            
            %% Pr file loading
            
            app.rawDistanceDistribution(:, :, app.numberOfTimesPrLoadingButtonHit) = distanceDistributionLoader(app);
            
            %% Plotting Pr file for targeting
            
            AxesForPrTargetingPlotter(app)
            
            %% Change tab to second tab
            
            changingTabToSecondTabForPrSelecting(app)
            
            %% Selecting the Pr target
            
            app.distanceDistributionTarger(app.numberOfTimesPrLoadingButtonHit) = distanceDistributionTargetSelector(app);
            
            %% Plotting the xline for targeting
            
            distanceDistributionTargerXlinePlotter(app)

        end

        % Button pushed function: ClicktoinputButton
        function ClicktoinputButtonPushed(app, event)
            %% appending the table with current inputs
            
            appendingDEERRefineRestraintsTable(app)
            
            %% clear the old axes
            
            clearAxesForPrTargeting(app)
            
            %% change the tab to major input tab
            
            changingTabToFirstTabForInput(app)
            
            %% set both residues as red color for next inputs
            
            checkingResiduesEditFieldRed(app)
            
            %% reset the residues input
            
            resetResiduesEditFieldValue(app)
            
            %% change the RMSE
            
            RMSEAutoDeterminator(app)
            
%             PrRefresher(app)
            
        end

        % Value changed function: Residue1EditField
        function Residue1EditFieldValueChanged(app, event)
            %% change residue 1 value
            
            app.spinPairResidue1(app.numberOfTimesPrLoadingButtonHit) = app.Residue1EditField.Value;
            
            %% turn the value green
            
            checkingResidue1EditField(app)
        end

        % Value changed function: Residue2EditField
        function Residue2EditFieldValueChanged(app, event)
            %% change residue 2 value
            
            app.spinPairResidue2(app.numberOfTimesPrLoadingButtonHit) = app.Residue2EditField.Value;
            
            %% turn the value green
            
            checkingResidue2EditField(app)
        end

        % Value changed function: SelectingRemovingSpinPairSpinner
        function SelectingRemovingSpinPairSpinnerValueChanged(app, event)
            %% Make the whole table white
            
            tableRemovingAllColor(app)
            
            %% Select the specific row
            
            app.rowOfTableToBeColored = rowOfTableRemovingSelector(app);
            
            %% Coloring the selected row
            
            rowOfTableRemovingColorer(app)
        end

        % Button pushed function: ClickToRemoveSelectedPrButton
        function ClickToRemoveSelectedPrButtonPushed(app, event)
            %% Make the whole table white
            
            tableRemovingAllColor(app)
            
            %% Delete the selected row
            
            selectedRowOfTableDeletor(app)
            
            %% Delete the selected Pr
            
            selectedPrDeletor(app)
            
            %% rewrite spin pair number of table
            
            sortingDEERRefineRestraintsTable(app)
            
            %% rewrite spin pair of file name
            
            selectedPrFileNameDeletor(app)
            
            PrRefresher(app)
            
            %% change the RMSE
            
            RMSEAutoDeterminator(app)
        end

        % Button pushed function: ValidateButton
        function ValidateButtonPushed(app, event)
            app.validatingFlag = 0;
            app.EditField.Value = "Creating working directory";
            drawnow
            
            %% refresh DEER restaints
            
            PrRefresher(app)
            
            %% create run file names
            
            app.runFileName = runFileNameCreator(app);
            
            %% create working folder
            
            workingFolderCreator(app)
            
            %% copy files
            
            DEERefineFileCopier(app)
            
            %% create advanced Pr file
            
            advancedPrFileCreator(app)
            
            %% create Pr files
            
            PrFilesCreator(app)
            
            %% NMR initial structure determination
            
            NMRStructureChecker(app)
            
            %% create log
            
            logCreator(app)
            
            %% create run files
            
            runFilesCreator(app)
            
            %% loop residue defining
            
            app.flexiblePhiPsiIndex = loopRegionString2FlexiblePhiPsiIndex(app);
            
            %% create RMSE passede Number files
            
            RMSEPassedNumberFilesCreator(app)
            
            %% create current status files
            
            currentStatusCreator(app)
            
            app.structureIndexEachScript = round(app.numberOfStructure*4);
            
            structureSaver(app)
            
            app.EditField.Value = "Working directory created";
            
            DEERefineMFileChecker(app)
            
            app.validatingFlag = 1;
        end

        % Value changed function: FlexibleregionsEditField
        function FlexibleregionsEditFieldValueChanged(app, event)
%             app.loopRegionString = app.FlexibleregionsEditField.Value;
%             app.phiPsiAngle = app.phipsiangleEditField.Value;
            app.FlexibleregionsEditField.FontColor = [0 0 0];
            
        end

        % Value changed function: phipsiangleEditField
        function phipsiangleEditFieldValueChanged(app, event)
            app.phipsiangleEditField.FontColor = [0 0 0];
        end

        % Value changed function: MCiterationEditField
        function MCiterationEditFieldValueChanged(app, event)
            app.MCiterationEditField.FontColor = [0 0 0];
        end

        % Value changed function: MCtempEditField
        function MCtempEditFieldValueChanged(app, event)
            app.MCtempEditField.FontColor = [0 0 0];
        end

        % Value changed function: RMSEEditField
        function RMSEEditFieldValueChanged(app, event)
            app.RMSEEditField.FontColor = [0 0 0];
        end

        % Value changed function: MaximalclashesEditField
        function MaximalclashesEditFieldValueChanged(app, event)
            app.MaximalclashesEditField.FontColor = [0 0 0];
        end

        % Value changed function: NumberofstructureEditField
        function NumberofstructureEditFieldValueChanged(app, event)
            app.NumberofstructureEditField.FontColor = [0 0 0];
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            parameterApplier(app)
            parameterChecker(app)
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if app.fullyStartFlag == 1
                cancel(app.DEERRefineJob);
                stop(app.currentStatusDetectorTimer)
            else
                return
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if app.validatingFlag == 1
                app.fullyStartFlag = 0;
                app.currentStatusDetectorTimer.TimerFcn = @(~, ~) currentStatusPresenter(app);
                start(app.currentStatusDetectorTimer)
                app.DEERRefineJob = runFilesExecutor(app);
                app.fullyStartFlag = 1;
            else
                return
            end
        end

        % Button pushed function: ResettargetdistanceButton
        function ResettargetdistanceButtonPushed(app, event)
            delete(findobj(app.AxesForPrTargeting.Children, 'Type', 'ConstantLine'))
            delete(findobj(app.AxesForPrTargeting.Children, 'Type', 'Point'))
            
            
            app.distanceDistributionTarger(app.numberOfTimesPrLoadingButtonHit) = distanceDistributionTargetSelector(app);
            
            %% Plotting the xline for targeting
            
            distanceDistributionTargerXlinePlotter(app)
        end

        % Value changed function: PDBevolutionCheckBox
        function PDBevolutionCheckBoxValueChanged(app, event)
            app.PDBEvolution = app.PDBevolutionCheckBox.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DEERefinerUIFigure = uifigure('Visible', 'off');
            app.DEERefinerUIFigure.Name = 'DEERefiner';
            app.GridLayout = uigridlayout(app.DEERefinerUIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.INPUTgeneralTab = uitab(app.TabGroup);
            app.INPUTgeneralTab.Title = 'INPUT- general';
            app.GridLayout2 = uigridlayout(app.INPUTgeneralTab);
            app.INPUTinitialstructurePanel = uipanel(app.GridLayout2);
            app.INPUTinitialstructurePanel.Title = 'INPUT- initial structure';
            app.BrowseButton_initial_struc = uibutton(app.INPUTinitialstructurePanel, 'push');
            app.BrowseButton_initial_struc.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_initial_strucPushed, true);
            app.BrowseButton_initial_struc.Text = 'Browse';
            app.InitialStructureEditField = uieditfield(app.INPUTinitialstructurePanel, 'text');
            app.InitialStructureEditField.Editable = 'off';
            app.SelectinputPDBfileEditFieldLabel = uilabel(app.INPUTinitialstructurePanel);
            app.SelectinputPDBfileEditFieldLabel.Text = 'Select input PDB file';
            app.BrowseButton_P_r = uibutton(app.INPUTinitialstructurePanel, 'push');
            app.BrowseButton_P_r.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_P_rPushed, true);
            app.BrowseButton_P_r.Text = 'Browse';
            app.SelectPrfilesEditFieldLabel = uilabel(app.INPUTinitialstructurePanel);
            app.SelectPrfilesEditFieldLabel.Text = 'Select P(r) files';
            app.SelectPrfilesEditField = uieditfield(app.INPUTinitialstructurePanel, 'text');
            app.SelectPrfilesEditField.Editable = 'off';
            app.ValidationPanel = uipanel(app.GridLayout2);
            app.ValidationPanel.Title = 'Validation';
            app.ValidateButton = uibutton(app.ValidationPanel, 'push');
            app.ValidateButton.ButtonPushedFcn = createCallbackFcn(app, @ValidateButtonPushed, true);
            app.ValidateButton.Text = 'Validate';
            app.StartButton = uibutton(app.ValidationPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.WaitingForCPUCoresDetectedCheckBox = uicheckbox(app.ValidationPanel);
            app.WaitingForCPUCoresDetectedCheckBox.Text = 'Waiting for CPU cores detected';
            app.WaitingformfilesdelectedCheckBox = uicheckbox(app.ValidationPanel);
            app.WaitingformfilesdelectedCheckBox.Text = 'Waiting for .m files delected';
            app.WaitingforcheckingPDBfileCheckBox = uicheckbox(app.ValidationPanel);
            app.WaitingforcheckingPDBfileCheckBox.Text = 'Waiting for checking PDB file';
            app.EditField = uieditfield(app.ValidationPanel, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Value = 'Current status';
            app.StopButton = uibutton(app.ValidationPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.WaitingForValidatingParameters = uicheckbox(app.ValidationPanel);
            app.WaitingForValidatingParameters.Text = 'Waiting for validating parameters';
            app.Stage1EditFieldLabel = uilabel(app.ValidationPanel);
            app.Stage1EditFieldLabel.Text = {'Stage 1'; ''};
            app.Stage1EditField = uieditfield(app.ValidationPanel, 'numeric');
            app.Stage1EditField.ValueDisplayFormat = '%.0f';
            app.Stage1EditField.Editable = 'off';
            app.Stage2EditFieldLabel = uilabel(app.ValidationPanel);
            app.Stage2EditFieldLabel.Text = 'Stage 2';
            app.Stage2EditField = uieditfield(app.ValidationPanel, 'numeric');
            app.Stage2EditField.ValueDisplayFormat = '%.0f';
            app.Stage2EditField.Editable = 'off';
            app.Stage3EditFieldLabel = uilabel(app.ValidationPanel);
            app.Stage3EditFieldLabel.Text = 'Stage 3';
            app.Stage3EditField = uieditfield(app.ValidationPanel, 'numeric');
            app.Stage3EditField.ValueDisplayFormat = '%.0f';
            app.Stage3EditField.Editable = 'off';
            app.Stage4EditFieldLabel = uilabel(app.ValidationPanel);
            app.Stage4EditFieldLabel.Text = 'Stage 4';
            app.Stage4EditField = uieditfield(app.ValidationPanel, 'numeric');
            app.Stage4EditField.ValueDisplayFormat = '%.0f';
            app.Stage4EditField.Editable = 'off';
            app.Stage5EditFieldLabel = uilabel(app.ValidationPanel);
            app.Stage5EditFieldLabel.Text = 'Stage 5';
            app.Stage5EditField = uieditfield(app.ValidationPanel, 'numeric');
            app.Stage5EditField.ValueDisplayFormat = '%.0f';
            app.Stage5EditField.Editable = 'off';
            app.AdvancedoptionsPanel = uipanel(app.GridLayout2);
            app.AdvancedoptionsPanel.Title = 'Advanced options';
            app.FlexibleregionsEditFieldLabel = uilabel(app.AdvancedoptionsPanel);
            app.FlexibleregionsEditFieldLabel.Text = 'Flexible regions';
            app.FlexibleregionsEditField = uieditfield(app.AdvancedoptionsPanel, 'text');
            app.FlexibleregionsEditField.ValueChangedFcn = createCallbackFcn(app, @FlexibleregionsEditFieldValueChanged, true);
            app.FlexibleregionsEditField.Value = '1:10 47:83';
            app.ApplyButton = uibutton(app.AdvancedoptionsPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.phipsiangleEditFieldLabel = uilabel(app.AdvancedoptionsPanel);
            app.phipsiangleEditFieldLabel.Text = 'phi/psi angle';
            app.phipsiangleEditField = uieditfield(app.AdvancedoptionsPanel, 'numeric');
            app.phipsiangleEditField.ValueChangedFcn = createCallbackFcn(app, @phipsiangleEditFieldValueChanged, true);
            app.phipsiangleEditField.Value = 0.4;
            app.MCiterationEditFieldLabel = uilabel(app.AdvancedoptionsPanel);
            app.MCiterationEditFieldLabel.Text = 'MC iteration';
            app.MCiterationEditField = uieditfield(app.AdvancedoptionsPanel, 'numeric');
            app.MCiterationEditField.ValueDisplayFormat = '%d';
            app.MCiterationEditField.ValueChangedFcn = createCallbackFcn(app, @MCiterationEditFieldValueChanged, true);
            app.MCiterationEditField.Value = 25000;
            app.MCtempEditFieldLabel = uilabel(app.AdvancedoptionsPanel);
            app.MCtempEditFieldLabel.Text = 'MC temp.';
            app.MCtempEditField = uieditfield(app.AdvancedoptionsPanel, 'numeric');
            app.MCtempEditField.ValueDisplayFormat = '%.5f';
            app.MCtempEditField.ValueChangedFcn = createCallbackFcn(app, @MCtempEditFieldValueChanged, true);
            app.MCtempEditField.Value = 0.05;
            app.RMSEEditFieldLabel = uilabel(app.AdvancedoptionsPanel);
            app.RMSEEditFieldLabel.Text = 'RMSE';
            app.RMSEEditField = uieditfield(app.AdvancedoptionsPanel, 'numeric');
            app.RMSEEditField.ValueDisplayFormat = '%.2f';
            app.RMSEEditField.ValueChangedFcn = createCallbackFcn(app, @RMSEEditFieldValueChanged, true);
            app.RMSEEditField.Value = 1.6;
            app.MaximalclashesEditFieldLabel = uilabel(app.AdvancedoptionsPanel);
            app.MaximalclashesEditFieldLabel.Text = 'Maximal clashes';
            app.MaximalclashesEditField = uieditfield(app.AdvancedoptionsPanel, 'numeric');
            app.MaximalclashesEditField.ValueDisplayFormat = '%d';
            app.MaximalclashesEditField.ValueChangedFcn = createCallbackFcn(app, @MaximalclashesEditFieldValueChanged, true);
            app.MaximalclashesEditField.Value = 3;
            app.NumberofstructureEditFieldLabel = uilabel(app.AdvancedoptionsPanel);
            app.NumberofstructureEditFieldLabel.Text = 'Number of structure';
            app.NumberofstructureEditField = uieditfield(app.AdvancedoptionsPanel, 'numeric');
            app.NumberofstructureEditField.ValueDisplayFormat = '%d';
            app.NumberofstructureEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofstructureEditFieldValueChanged, true);
            app.NumberofstructureEditField.Value = 20;
            app.UsageofcoresEditFieldLabel = uilabel(app.AdvancedoptionsPanel);
            app.UsageofcoresEditFieldLabel.Text = 'Usage of cores';
            app.UsageofcoresEditField = uieditfield(app.AdvancedoptionsPanel, 'numeric');
            app.UsageofcoresEditField.ValueDisplayFormat = '%d';
            app.UsageofcoresEditField.Value = 4;
            app.PDBevolutionCheckBox = uicheckbox(app.AdvancedoptionsPanel);
            app.PDBevolutionCheckBox.ValueChangedFcn = createCallbackFcn(app, @PDBevolutionCheckBoxValueChanged, true);
            app.PDBevolutionCheckBox.Text = 'PDB evolution';
            app.INPUTPrTab = uitab(app.TabGroup);
            app.INPUTPrTab.Title = 'INPUT- P(r)';
            app.AxesForPrTargeting = uiaxes(app.INPUTPrTab);
            title(app.AxesForPrTargeting, 'Title')
            xlabel(app.AxesForPrTargeting, 'Distance, nm')
            ylabel(app.AxesForPrTargeting, 'P(r)')
            zlabel(app.AxesForPrTargeting, 'Z')
            app.ClicktoinputButton = uibutton(app.INPUTPrTab, 'push');
            app.ClicktoinputButton.ButtonPushedFcn = createCallbackFcn(app, @ClicktoinputButtonPushed, true);
            app.ClicktoinputButton.Text = 'Click to input';
            app.DEERRefineRestraintsTable = uitable(app.INPUTPrTab);
            app.DEERRefineRestraintsTable.ColumnName = {'Spin pair #'; 'Residue 1'; 'Residue 2'; 'Target distance (nm)'};
            app.DEERRefineRestraintsTable.RowName = {};
            app.Residue1EditFieldLabel = uilabel(app.INPUTPrTab);
            app.Residue1EditFieldLabel.Text = 'Residue 1';
            app.Residue1EditField = uieditfield(app.INPUTPrTab, 'numeric');
            app.Residue1EditField.ValueDisplayFormat = '%d';
            app.Residue1EditField.ValueChangedFcn = createCallbackFcn(app, @Residue1EditFieldValueChanged, true);
            app.Residue2EditFieldLabel = uilabel(app.INPUTPrTab);
            app.Residue2EditFieldLabel.Text = 'Residue 2';
            app.Residue2EditField = uieditfield(app.INPUTPrTab, 'numeric');
            app.Residue2EditField.ValueDisplayFormat = '%.0f';
            app.Residue2EditField.ValueChangedFcn = createCallbackFcn(app, @Residue2EditFieldValueChanged, true);
            app.TargetdistancenmEditFieldLabel = uilabel(app.INPUTPrTab);
            app.TargetdistancenmEditFieldLabel.Text = 'Target distance, nm';
            app.TargetDistanceEditField = uieditfield(app.INPUTPrTab, 'numeric');
            app.TargetDistanceEditField.Value = 2;
            app.SelectremovingspinpairSpinnerLabel = uilabel(app.INPUTPrTab);
            app.SelectremovingspinpairSpinnerLabel.Text = 'Select removing spin pair #';
            app.SelectingRemovingSpinPairSpinner = uispinner(app.INPUTPrTab);
            app.SelectingRemovingSpinPairSpinner.Limits = [0 Inf];
            app.SelectingRemovingSpinPairSpinner.ValueDisplayFormat = '%d';
            app.SelectingRemovingSpinPairSpinner.ValueChangedFcn = createCallbackFcn(app, @SelectingRemovingSpinPairSpinnerValueChanged, true);
            app.ClickToRemoveSelectedPrButton = uibutton(app.INPUTPrTab, 'push');
            app.ClickToRemoveSelectedPrButton.ButtonPushedFcn = createCallbackFcn(app, @ClickToRemoveSelectedPrButtonPushed, true);
            app.ClickToRemoveSelectedPrButton.Text = 'Click to remove selected P(r)';
            app.Label = uilabel(app.INPUTPrTab);
            app.Label.Text = '----------------------------------------------------';
            app.ResettargetdistanceButton = uibutton(app.INPUTPrTab, 'push');
            app.ResettargetdistanceButton.ButtonPushedFcn = createCallbackFcn(app, @ResettargetdistanceButtonPushed, true);
            app.ResettargetdistanceButton.Text = 'Reset target distance';
            % Show the figure after all components are created
            app.DEERefinerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DEERefiner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DEERefinerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DEERefinerUIFigure)
        end
    end
end